classdef logPressure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DataDisplayPanel               matlab.ui.container.Panel
        TabGroupData                   matlab.ui.container.TabGroup
        GraphixTabData                 matlab.ui.container.Tab
        OFFLabelCH1                    matlab.ui.control.Label
        CH1Label                       matlab.ui.control.Label
        CH3Label                       matlab.ui.control.Label
        v                              matlab.ui.control.Label
        OFFLabelCH3                    matlab.ui.control.Label
        OFFLabelCH2                    matlab.ui.control.Label
        TC08TabData                    matlab.ui.container.Tab
        UITableTC08                    matlab.ui.control.Table
        RGATabData                     matlab.ui.container.Tab
        UITableRGA                     matlab.ui.control.Table
        LogTab                         matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
        StatusPanel                    matlab.ui.container.Panel
        DisconnectedLampRGA            matlab.ui.control.Lamp
        DisconnectedLamp_3Label        matlab.ui.control.Label
        RGALabel                       matlab.ui.control.Label
        TC08Label                      matlab.ui.control.Label
        DisconnectedLampTC08           matlab.ui.control.Lamp
        DisconnectedLamp_2Label        matlab.ui.control.Label
        GraphixLabel                   matlab.ui.control.Label
        DisconnectedLampG              matlab.ui.control.Lamp
        DisconnectedLampLabel          matlab.ui.control.Label
        ExportDataPanel                matlab.ui.container.Panel
        SaveButton                     matlab.ui.control.Button
        FilenameEditField              matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        MeasurementSetUpPanel          matlab.ui.container.Panel
        AutosavedatadirectoryCheckBox  matlab.ui.control.CheckBox
        SampleGeometryDropDown         matlab.ui.control.DropDown
        SampleGeometryDropDownLabel    matlab.ui.control.Label
        SampleNameEditField            matlab.ui.control.EditField
        SampleNameEditFieldLabel       matlab.ui.control.Label
        MeasurementTypeDropDown        matlab.ui.control.DropDown
        MeasurementTypeDropDownLabel   matlab.ui.control.Label
        DurationEditField              matlab.ui.control.NumericEditField
        DurationEditFieldLabel         matlab.ui.control.Label
        IntervalsSpinner               matlab.ui.control.Spinner
        IntervalsSpinnerLabel          matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        AbortButton                    matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        ConnectDevicesPanel            matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        GraphixTab                     matlab.ui.container.Tab
        PortDropDownG                  matlab.ui.control.DropDown
        PortDropDownLabel              matlab.ui.control.Label
        ButtonGroupG                   matlab.ui.container.ButtonGroup
        DisconnectButton               matlab.ui.control.ToggleButton
        ConnectButton                  matlab.ui.control.ToggleButton
        TC08Tab                        matlab.ui.container.Tab
        ButtonGroupTC08                matlab.ui.container.ButtonGroup
        DisconnectButtonTC08           matlab.ui.control.ToggleButton
        ConnectButtonTC08              matlab.ui.control.ToggleButton
        RGATab                         matlab.ui.container.Tab
        HighMassSpinner                matlab.ui.control.Spinner
        HighMassSpinnerLabel           matlab.ui.control.Label
        LowMassSpinner                 matlab.ui.control.Spinner
        LowMassSpinnerLabel            matlab.ui.control.Label
        PortDropDownRGA                matlab.ui.control.DropDown
        PortDropDown_2Label            matlab.ui.control.Label
        ButtonGroupRGA                 matlab.ui.container.ButtonGroup
        DisconnectButtonRGA            matlab.ui.control.ToggleButton
        ConnectButtonRGA               matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        grx = 0; % Description
        rga = 0
        isAbortLog = 0; % Description
        t_arr = 0; % Description
        p1_arr = 0
        p2_arr = 0;
        p3_arr = 0;
        pp_arr = 0;
        unithandle = 0; % Description
        temp_arr = 0;
        out_table = 0
    end
    
    methods (Access = private)
        
        function fil_status = checkFilament(app)
            writeline(app.rga, "fi")
            fil_resp = readline(app.rga)
            fil_status_arr = split(strtrim(fil_resp));
            fil_status_str = fil_status_arr(2);
            fil_status = str2double(fil_status_str);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % on start clear variables. This deletes any serialport which
            % have not been closed
            clear
        end

        % Selection changed function: ButtonGroupG
        function ButtonGroupGSelectionChanged(app, event)
            selectedButton = app.ButtonGroupG.SelectedObject;
            
            % connect if connect button is pressed
            if app.ConnectButton.Value
                try
                    app.grx = serialport(app.PortDropDownG.Value, 9600); % open serialport with name and baud rate
                    configureTerminator(app.grx, 04); % set terminator (EOT in graphix manual)
                    flush(app.grx);
                    % Lamp indicates connection status (green=connected)
                    app.DisconnectedLampLabel.Text = "Connected";
                    app.DisconnectedLampG.Color = [0 1 0];
                    % enable logging panel
                    children = get(app.MeasurementSetUpPanel,'Children');
                    set(children(isprop(children,'Enable')),'Enable','on');
                    app.AbortButton.Enable = "off";
                catch ME
                    % Indicate connection error
                    app.DisconnectedLampLabel.Text = "Connection Error";
                    app.DisconnectedLampG.Color = [1 0 0];
                    rethrow(ME)
                end
            end
            % Disconnect if disconnect button is pressed
            if app.DisconnectButton.Value
                app.grx = []; % disconnect by deleting serialport variable
                % indicate disconnection
                app.DisconnectedLampLabel.Text = "Disconnected";
                app.DisconnectedLampG.Color = [1 1 1];
                % Disable logging panel
                children = get(app.MeasurementSetUpPanel,'Children');
                set(children(isprop(children,'Enable')),'Enable','off');
            end
        end

        % Button pushed function: StartButton
        function StartLogging(app, event)
            
            % Disable everything but abort. Should not start logging again
            % until run is complete or aborted.
            log_children = get(app.MeasurementSetUpPanel,'Children');
            set(log_children(isprop(log_children,'Enable')),'Enable','off');
            app.AbortButton.Enable = "on";
            
            % disable export
            export_children = get(app.ExportDataPanel,'Children');
            set(export_children(isprop(export_children,'Enable')),'Enable','off');
            
            % format datetime for filename
            fn_date = string(datetime('now', "Format", 'yyyyMMdd_HHmmss'));
            % Add measurement info to filename
            if app.MeasurementTypeDropDown.Value == "Reference"
                app.FilenameEditField.Value = strcat(fn_date, "_ref", ".txt");
            end
            % for measurements with samples add sample geometry + name
            if app.MeasurementTypeDropDown.Value == "Sample"
                % remove spaces from name
                fn_sample = strrep(app.SampleNameEditField.Value, " ", "");
                fn_geom = app.SampleGeometryDropDown.Value;
                app.FilenameEditField.Value = strcat(fn_date, '_', fn_sample, '_', fn_geom, ".txt");
            end
            
            
            % get duration and interval from input
            if app.DropDown.Value == "sec"
                duration = app.DurationEditField.Value;
            elseif app.DropDown.Value == "mins"
                duration = app.DurationEditField.Value * 60;
            elseif app.DropDown.Value == "hrs"
                duration = app.DurationEditField.Value * 60 * 60;
            end
            
            interval = app.IntervalsSpinner.Value;
            
            %% TC-08 Set up
            % -----------------------------------------------------------------------------
            if app.ConnectButtonTC08.Value
                typek = 'K'; % Sensor type
                % pointer objects for tc-08 api commands. This is a c thing that i
                % don't really understand :)
                pBufferChs = libpointer('singlePtr', zeros(1, 9, 'single'));
                overflow = libpointer('int16Ptr', zeros(1, 1, 'int16'));
    
                % Enable all 8 channels
                for n = 2:9
                    status.setChannel = calllib('usbtc08', 'usb_tc08_set_channel', app.unithandle, ...
                        (n - 1), int8(typek));
                end
    
                % Set mains filter to 50Hz
                status.mainsFilter = calllib('usbtc08', 'usb_tc08_set_mains', app.unithandle, 0);
            end
            
            % -----------------------------------------------------------------------------
            %% Graphix set up
            % -----------------------------------------------------------------------------
            % commands for pressures
            pcom1 = "1;29";
            pcom2 = "2;29";
            pcom3 = "3;29";
            
            % send strings to get graphix pressures (see manual)
            sstr1 = makeSendString(pcom1);
            sstr2 = makeSendString(pcom2);
            sstr3 = makeSendString(pcom3);
            % -----------------------------------------------------------------------------
            n_samples = floor(duration/interval) + 1; % No. of samples
            
            %% RGA set up
            max_p = 1e-4;
            mass_span = app.HighMassSpinner.Value - (app.LowMassSpinner.Value - 1);
            scan_status = 0;

            % Preallocate arrays
            app.t_arr = NaT(n_samples, 1);
            app.p1_arr = zeros(n_samples, 1);
            app.p2_arr = zeros(n_samples, 1);
            app.p3_arr = zeros(n_samples, 1);
            app.temp_arr = zeros(n_samples, 9);
            app.pp_arr = NaN(n_samples, mass_span);
            
            % start sampling loop
            counter = 1;
            while counter <= n_samples
                
                t = datetime; % get time
                
                % get pressures from graphix
                resp1 = char(writeread(app.grx, sstr1));
                resp2 = char(writeread(app.grx, sstr2));
                resp3 = char(writeread(app.grx, sstr3));
                
                % Get temps from tc-08
                if app.ConnectButtonTC08.Value
                    [~, temps, ~] = calllib('usbtc08', 'usb_tc08_get_single', app.unithandle, pBufferChs, overflow, 0);
                end
                % get partial pressures from rga
                % check if the RGA has returned a measurement
%                writeline(app.rga, "sd");
%                rga_resp = readline(app.rga)
                
                % check if abort button has been pressed
                % why is this here?
                if app.isAbortLog
                    break
                end
                                          
                % convert from receive string to pressure
                % receive strings are in format *p* where * are ascii
                % chars
                p1_str = resp1(2:end-1);
                p2_str = resp2(2:end-1);
                p3_str = resp3(2:end-1);
                
                p1 = str2double(p1_str);
                p2 = str2double(p2_str);
                p3 = str2double(p3_str);                
                
                % display pressure
                app.OFFLabelCH1.Text = strcat(p1_str, ' mbar');
                app.OFFLabelCH2.Text = strcat(p2_str, ' mbar');
                app.OFFLabelCH3.Text = strcat(p3_str, ' mbar');
                
                % check pressure for rga
                if app.ConnectButtonRGA.Value
                    if p2 > max_p
                        % if pressure is too high then abort
                        disp("Overpressure")
                        if scan_status
                            writeread(app.rga, "ra")
                            disp("Aborting Scan")
                            scan_status = 0;
                            % check filament status and turn off if necessary
                            % pause for abort to complete and flush
                            pause(1)
                            flush(app.rga)
                            fil_status = checkFilament(app);
                            if fil_status
                                writeread(app.rga, "F1")
                                disp("Filament Off - OP")
                            end
                        end
                        %total max loop time = 2 + 2 reads
                    else
                        % if pressure is OK start and a scan isn't running
                        % start a scan
                        if ~scan_status
                            % no pause as nothing should be running
                            flush(app.rga)
                            fil_status = checkFilament(app);
                            if ~fil_status
                                writeread(app.rga, "F1")
                                disp("Start Scan. Filament On")
                            else
                                disp("Start Scan")
                            end
                            writeread(app.rga, "START")
                            % start scanning loop
                            % pause to flush (probably not necessary)
                            pause(1)
                            flush(app.rga)
                            configureCallback(app.rga, "terminator", @readSerial)
                            writeread(app.rga, "cd")
                            disp("Get first measurement")
                            scan_status = 1;
                        else
                            % if a scan is in progress
                            disp("Reading Scan")
                            
                            rga_resp = app.rga.UserData
                            if ~isempty(rga_resp)
                                pps = readRGAOutput(rga_resp)
                                if length(pps) == mass_span 
                                    app.pp_arr(counter, :) = pps;
                                    app.UITableRGA.Data = pps.';
                                    disp("Got Scan")
                                end
                                disp("Get a new scan");
                            else
                                disp("No Scan Available")
                            end
                        end                        
                    end
                end
                
                
                % update arrays
                app.t_arr(counter) = t;
                app.p1_arr(counter) = p1;
                app.p2_arr(counter) = p2;
                app.p3_arr(counter) = p3;
                
                if app.ConnectButtonTC08.Value
                    app.temp_arr(counter, :) = temps;
                    app.UITableTC08.Data = temps;
                end

                counter = counter + 1; % increment counter
                
                % wait until time for next measurement
                run_time = seconds(datetime - t);
                pause(interval - run_time);
            end
            % create tables for each variable
            % datetime
            datetime_table = table(app.t_arr, 'VariableNames', "Datetime");
            % pressure
            p_table = table(app.p1_arr, app.p2_arr, app.p3_arr, 'VariableNames', ["CH1", "CH2", "CH3"]);
            % Temp
            temp_names = "T" + string(1:9);
            temp_table = array2table(app.temp_arr, 'VariableNames', temp_names);
            % partial pressures
            pp_names = "M" + string(app.LowMassSpinner.Value:app.HighMassSpinner.Value);
            pp_table = array2table(app.pp_arr, 'VariableNames', pp_names);
            
            app.out_table = [datetime_table, p_table, temp_table, pp_table];
            % Autosave to f: if requested
            if app.AutosavedatadirectoryCheckBox.Value == true
                % F drive not on admin account. Autosave locally as
                % temporary fix
                %autosave_path = "\\feronia\sys\temp\Catkins\OG_Pressure_data\";
                autosave_path = "C:\Users\and19834\Documents\am4space_outgassing\OG_data\autosave\";
                if isfolder(autosave_path)
                    writetable(app.out_table, strcat(autosave_path, app.FilenameEditField.Value));
                else
                    disp("Autosave ERROR: Autosave path does not exist. Please save file manually.")
                end
            end
            
            if app.ConnectButtonRGA.Value
                % Turn off the filament
                writeline(app.rga, "ra")
                pause(0.5)
                flush(app.rga)
                writeread(app.rga, "F1")
                flush(app.rga)
            end
            
            % Reset pressure display
            app.OFFLabelCH1.Text = "OFF";
            app.OFFLabelCH2.Text = "OFF";
            app.OFFLabelCH3.Text = "OFF";
            set(log_children(isprop(log_children,'Enable')),'Enable','on');
            app.AbortButton.Enable = "off";
            app.isAbortLog = 0;
            
            % enable export panel            
            set(export_children(isprop(export_children,'Enable')),'Enable','on');
            
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            app.isAbortLog = 1;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Manual save option
            % use file explorer to select path
            [fn, path] = uiputfile(app.FilenameEditField.Value);
                    writetable(app.out_table, strcat(path,fn));
            
        end

        % Value changed function: MeasurementTypeDropDown
        function MeasurementTypeDropDownValueChanged(app, event)
            % for sample measurements display geometry and name fields
            value = app.MeasurementTypeDropDown.Value;
            if value == "Sample"
                app.SampleNameEditField.Visible = "on";
                app.SampleGeometryDropDownLabel.Visible = "on";
                app.SampleGeometryDropDown.Visible = "on";
                app.SampleNameEditFieldLabel.Visible = "on";                
            end
            if value == "Reference"
                app.SampleNameEditField.Visible = "off";
                app.SampleGeometryDropDownLabel.Visible = "off";
                app.SampleGeometryDropDown.Visible = "off";
                app.SampleNameEditFieldLabel.Visible = "off";      
            end
        end

        % Selection changed function: ButtonGroupTC08
        function ButtonGroupTC08SelectionChanged(app, event)
            selectedButton = app.ButtonGroupTC08.SelectedObject;
            % TC-08 connection
            if app.ConnectButtonTC08.Value
                % configuration script
                % configures device and loads command libraries
                USBTC08Config;
                % connect to device
                app.unithandle = calllib('usbtc08', 'usb_tc08_open_unit');
                % Veirfy connection status
                if app.unithandle > 0
                    disp('Connected to USB TC-08 device...');
                    fprintf('\n');
                    % Lamp indicates connection status (green=connected)
                    app.DisconnectedLamp_2Label.Text = "Connected";
                    app.DisconnectedLampTC08.Color = [0 1 0];
                elseif app.unithandle == 0
                    % Lamp indicates connection status (red=error)
                    app.DisconnectedLamp_2Label.Text = "Connection Error";
                    app.DisconnectedLampTC08.Color = [1 0 0];
                    warning('USBTC08Example:NoMoreUnitsFound', 'No more USB TC-08 units found');
                else
                     % Lamp indicates connection status (red=error)
                    app.DisconnectedLamp_2Label.Text = "Connection Error";
                    app.DisconnectedLampTC08.Color = [0 1 0];
                    % unload the command library to allow reconnection
                    % attempt
                    unloadlibrary('usbtc08');
                    error('USBTC08Example:UnitFailedToOpen', 'Unit failed to open.');
                    return;
                end
            end
            % disconnect and clean up
            if app.DisconnectButtonTC08.Value
                stop = calllib('usbtc08', 'usb_tc08_stop', app.unithandle);
                exit = calllib('usbtc08', 'usb_tc08_close_unit', app.unithandle);
                unloadlibrary('usbtc08');
                app.DisconnectedLamp_2Label.Text = "Disconnected";
                app.DisconnectedLampTC08.Color = [1 1 1];
                 
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % clean up if closed
            if app.ConnectButtonTC08.Value
                stop = calllib('usbtc08', 'usb_tc08_stop', app.unithandle);
                exit = calllib('usbtc08', 'usb_tc08_close_unit', app.unithandle);
                unloadlibrary('usbtc08');
            end
            delete(app)
        end

        % Selection changed function: ButtonGroupRGA
        function ButtonGroupRGASelectionChanged(app, event)
            selectedButton = app.ButtonGroupRGA.SelectedObject;
            % connect if connect button is pressed
            if app.ConnectButtonRGA.Value
                try
                    app.rga = serialport(app.PortDropDownRGA.Value, 9600); % open serialport with name and baud rate
                    configureTerminator(app.rga, "CR"); % set terminator
                    set(app.rga, 'timeout', 2)
                    flush(app.rga);
                    
                    if app.LowMassSpinner.Value > app.HighMassSpinner.Value
                        massME = MException('OGGUI:massRangeInvalid', "Mass range invalid");
                        throw(massME)
                    end

                    %% RGA set up
                    % Go to BAR mode
                    writeread(app.rga, "BAR")
                    
                    % Set measurement mass span
                    set_m_str = string(app.LowMassSpinner.Value) + " " + string(app.HighMassSpinner.Value) + " SPAN";
                    writeread(app.rga, set_m_str)
                    
                    m_names = "M" + string(1:30);
                    app.UITableRGA.ColumnName = m_names;
                    
                    % turn on autorange. Range will be intially set when
                    % measurement is started and autorange will adjust
                    writeread(app.rga, "-5 !rng")
                    writeread(app.rga, "RANGE")
                    writeread(app.rga, "AUTRNG")
                    
                    % set dwell. This sets how long each mass is measured
                    % for. Can be set manually or using presets which are calculated
                    % automatically. Setting to NORMAL preset (see manual
                    % pg 96)
                    writeread(app.rga, "MENUS")
                    writeread(app.rga, "ACQ")
                    writeread(app.rga, "DWELL")
                    writeread(app.rga, "NORMAL")
                    writeread(app.rga, "RETURN")
                    writeread(app.rga, "RETURN")
                    
                    % pause to allow commands complete and flush buffer
                    pause(1)
                    flush(app.rga)
                    
                    % check RGA filament status and turn off if required
                    fil_status = checkFilament(app);
                    if fil_status
                        writeread(app.rga, "F1");
                    end
                    
                    % Lamp indicates connection status (green=connected)
                    app.DisconnectedLamp_3Label.Text = "Connected";
                    app.DisconnectedLampRGA.Color = [0 1 0];
                    % Disable the mass range buttons
                    set(app.LowMassSpinner, "Enable", "off")
                    set(app.HighMassSpinner, "Enable", "off")

                catch ME
                    % Indicate connection error
                    app.DisconnectedLamp_3Label.Text = "Connection Error";
                    app.DisconnectedLampRGA.Color = [1 0 0];
                    rethrow(ME)
                end
            end
            % Disconnect if disconnect button is pressed
            if app.DisconnectButtonRGA.Value
                pause(1);
                flush(app.rga)
                abort = writeread(app.rga, "ra")
                writeline(app.rga, "RETURN")
                pause(1)
                flush(app.rga)
                fil_status = checkFilament(app);
                if fil_status
                    writeread(app.rga, "F1");
                end
                app.rga = []; % disconnect by deleting serialport variable
                % indicate disconnection
                app.DisconnectedLamp_3Label.Text = "Disconnected";
                app.DisconnectedLampRGA.Color = [1 1 1];
                set(app.LowMassSpinner, "Enable", "on")
                set(app.HighMassSpinner, "Enable", "on")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConnectDevicesPanel = uipanel(app.UIFigure);
            app.ConnectDevicesPanel.Title = 'Connect Devices';
            app.TabGroup = uitabgroup(app.ConnectDevicesPanel);
            app.GraphixTab = uitab(app.TabGroup);
            app.GraphixTab.Title = 'Graphix';
            app.ButtonGroupG = uibuttongroup(app.GraphixTab);
            app.ButtonGroupG.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupGSelectionChanged, true);
            app.ConnectButton = uitogglebutton(app.ButtonGroupG);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uitogglebutton(app.ButtonGroupG);
            app.DisconnectButton.Text = 'Disconnect';
            app.DisconnectButton.Value = true;
            app.PortDropDownLabel = uilabel(app.GraphixTab);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDownG = uidropdown(app.GraphixTab);
            app.PortDropDownG.Items = {'COM3', 'COM6', 'COM4', 'COM1', 'COM5'};
            app.PortDropDownG.Value = 'COM5';
            app.TC08Tab = uitab(app.TabGroup);
            app.TC08Tab.Title = 'TC-08';
            app.ButtonGroupTC08 = uibuttongroup(app.TC08Tab);
            app.ButtonGroupTC08.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupTC08SelectionChanged, true);
            app.ConnectButtonTC08 = uitogglebutton(app.ButtonGroupTC08);
            app.ConnectButtonTC08.Text = 'Connect';
            app.DisconnectButtonTC08 = uitogglebutton(app.ButtonGroupTC08);
            app.DisconnectButtonTC08.Text = 'Disconnect';
            app.DisconnectButtonTC08.Value = true;
            app.RGATab = uitab(app.TabGroup);
            app.RGATab.Title = 'RGA';
            app.ButtonGroupRGA = uibuttongroup(app.RGATab);
            app.ButtonGroupRGA.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupRGASelectionChanged, true);
            app.ConnectButtonRGA = uitogglebutton(app.ButtonGroupRGA);
            app.ConnectButtonRGA.Text = 'Connect';
            app.DisconnectButtonRGA = uitogglebutton(app.ButtonGroupRGA);
            app.DisconnectButtonRGA.Text = 'Disconnect';
            app.DisconnectButtonRGA.Value = true;
            app.PortDropDown_2Label = uilabel(app.RGATab);
            app.PortDropDown_2Label.Text = 'Port';
            app.PortDropDownRGA = uidropdown(app.RGATab);
            app.PortDropDownRGA.Items = {'COM3', 'COM6', 'COM4', 'COM1', 'COM5', '', ''};
            app.PortDropDownRGA.Value = 'COM4';
            app.LowMassSpinnerLabel = uilabel(app.RGATab);
            app.LowMassSpinnerLabel.Text = 'Low Mass';
            app.LowMassSpinner = uispinner(app.RGATab);
            app.LowMassSpinner.Limits = [1 100];
            app.LowMassSpinner.RoundFractionalValues = 'on';
            app.LowMassSpinner.Value = 1;
            app.HighMassSpinnerLabel = uilabel(app.RGATab);
            app.HighMassSpinnerLabel.Text = 'High Mass';
            app.HighMassSpinner = uispinner(app.RGATab);
            app.HighMassSpinner.Limits = [1 100];
            app.HighMassSpinner.RoundFractionalValues = 'on';
            app.HighMassSpinner.Value = 20;
            app.MeasurementSetUpPanel = uipanel(app.UIFigure);
            app.MeasurementSetUpPanel.Title = 'Measurement Set Up';
            app.StartButton = uibutton(app.MeasurementSetUpPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartLogging, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = {'Start'; ''};
            app.AbortButton = uibutton(app.MeasurementSetUpPanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Enable = 'off';
            app.AbortButton.Text = 'Abort';
            app.DropDown = uidropdown(app.MeasurementSetUpPanel);
            app.DropDown.Items = {'sec', 'mins', 'hrs', ''};
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'hrs';
            app.IntervalsSpinnerLabel = uilabel(app.MeasurementSetUpPanel);
            app.IntervalsSpinnerLabel.Enable = 'off';
            app.IntervalsSpinnerLabel.Text = 'Interval (s)';
            app.IntervalsSpinner = uispinner(app.MeasurementSetUpPanel);
            app.IntervalsSpinner.Limits = [1 Inf];
            app.IntervalsSpinner.Enable = 'off';
            app.IntervalsSpinner.Value = 10;
            app.DurationEditFieldLabel = uilabel(app.MeasurementSetUpPanel);
            app.DurationEditFieldLabel.Enable = 'off';
            app.DurationEditFieldLabel.Text = {'Duration'; ''};
            app.DurationEditField = uieditfield(app.MeasurementSetUpPanel, 'numeric');
            app.DurationEditField.Limits = [1 Inf];
            app.DurationEditField.Enable = 'off';
            app.DurationEditField.Value = 20;
            app.MeasurementTypeDropDownLabel = uilabel(app.MeasurementSetUpPanel);
            app.MeasurementTypeDropDownLabel.Enable = 'off';
            app.MeasurementTypeDropDownLabel.Text = 'Measurement Type';
            app.MeasurementTypeDropDown = uidropdown(app.MeasurementSetUpPanel);
            app.MeasurementTypeDropDown.Items = {'Reference', 'Sample', 'Other'};
            app.MeasurementTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MeasurementTypeDropDownValueChanged, true);
            app.MeasurementTypeDropDown.Enable = 'off';
            app.MeasurementTypeDropDown.Value = 'Reference';
            app.SampleNameEditFieldLabel = uilabel(app.MeasurementSetUpPanel);
            app.SampleNameEditFieldLabel.Visible = 'off';
            app.SampleNameEditFieldLabel.Text = 'Sample Name';
            app.SampleNameEditField = uieditfield(app.MeasurementSetUpPanel, 'text');
            app.SampleNameEditField.Visible = 'off';
            app.SampleGeometryDropDownLabel = uilabel(app.MeasurementSetUpPanel);
            app.SampleGeometryDropDownLabel.Visible = 'off';
            app.SampleGeometryDropDownLabel.Text = 'Sample Geometry';
            app.SampleGeometryDropDown = uidropdown(app.MeasurementSetUpPanel);
            app.SampleGeometryDropDown.Items = {'Cube', 'Lattice', 'Other'};
            app.SampleGeometryDropDown.Visible = 'off';
            app.SampleGeometryDropDown.Value = 'Cube';
            app.AutosavedatadirectoryCheckBox = uicheckbox(app.MeasurementSetUpPanel);
            app.AutosavedatadirectoryCheckBox.Enable = 'off';
            app.AutosavedatadirectoryCheckBox.Text = 'Autosave data directory:';
            app.AutosavedatadirectoryCheckBox.Value = true;
            app.ExportDataPanel = uipanel(app.UIFigure);
            app.ExportDataPanel.Title = 'Export Data';
            app.FilenameEditFieldLabel = uilabel(app.ExportDataPanel);
            app.FilenameEditFieldLabel.Enable = 'off';
            app.FilenameEditFieldLabel.Text = {'Filename'; ''};
            app.FilenameEditField = uieditfield(app.ExportDataPanel, 'text');
            app.FilenameEditField.Enable = 'off';
            app.FilenameEditField.Value = 'test.txt';
            app.SaveButton = uibutton(app.ExportDataPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save..';
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.DisconnectedLampLabel = uilabel(app.StatusPanel);
            app.DisconnectedLampLabel.Text = 'Disconnected';
            app.DisconnectedLampG = uilamp(app.StatusPanel);
            app.GraphixLabel = uilabel(app.StatusPanel);
            app.GraphixLabel.Text = 'Graphix:';
            app.DisconnectedLamp_2Label = uilabel(app.StatusPanel);
            app.DisconnectedLamp_2Label.Text = 'Disconnected';
            app.DisconnectedLampTC08 = uilamp(app.StatusPanel);
            app.TC08Label = uilabel(app.StatusPanel);
            app.TC08Label.Text = 'TC-08:';
            app.RGALabel = uilabel(app.StatusPanel);
            app.RGALabel.Text = 'RGA:';
            app.DisconnectedLamp_3Label = uilabel(app.StatusPanel);
            app.DisconnectedLamp_3Label.Text = 'Disconnected';
            app.DisconnectedLampRGA = uilamp(app.StatusPanel);
            app.DataDisplayPanel = uipanel(app.UIFigure);
            app.DataDisplayPanel.Title = 'Data Display';
            app.TabGroupData = uitabgroup(app.DataDisplayPanel);
            app.GraphixTabData = uitab(app.TabGroupData);
            app.GraphixTabData.Title = 'Graphix';
            app.OFFLabelCH2 = uilabel(app.GraphixTabData);
            app.OFFLabelCH2.Text = 'OFF';
            app.OFFLabelCH3 = uilabel(app.GraphixTabData);
            app.OFFLabelCH3.Text = 'OFF';
            app.v = uilabel(app.GraphixTabData);
            app.v.Text = 'CH2:';
            app.CH3Label = uilabel(app.GraphixTabData);
            app.CH3Label.Text = 'CH3:';
            app.CH1Label = uilabel(app.GraphixTabData);
            app.CH1Label.Text = 'CH1:';
            app.OFFLabelCH1 = uilabel(app.GraphixTabData);
            app.OFFLabelCH1.Text = 'OFF';
            app.TC08TabData = uitab(app.TabGroupData);
            app.TC08TabData.Title = 'TC-08 (deg C)';
            app.UITableTC08 = uitable(app.TC08TabData);
            app.UITableTC08.ColumnName = {'T1'; 'T2'; 'T3'; 'T4'; 'T5'; 'T6'; 'T7'; 'T8'; 'T9'};
            app.UITableTC08.RowName = {''};
            app.RGATabData = uitab(app.TabGroupData);
            app.RGATabData.Title = 'RGA';
            app.UITableRGA = uitable(app.RGATabData);
            app.UITableRGA.ColumnName = {'M1'; 'M2'; 'M3'; 'M4'; 'M5'};
            app.UITableRGA.RowName = {};
            app.LogTab = uitab(app.TabGroupData);
            app.LogTab.Title = 'Log';
            app.TextArea = uitextarea(app.LogTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = logPressure

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end